classdef run < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        RobotGoalLocationLabel     matlab.ui.control.Label
        RobotInitialLocationLabel  matlab.ui.control.Label
        ElapsedTimeTextArea        matlab.ui.control.TextArea
        ElapsedTimeTextAreaLabel   matlab.ui.control.Label
        ContinueButton             matlab.ui.control.Button
        PauseButton                matlab.ui.control.Button
        CommandsTextArea           matlab.ui.control.TextArea
        CommandsTextAreaLabel      matlab.ui.control.Label
        StopButton                 matlab.ui.control.Button
        Goal_YSpinner              matlab.ui.control.Spinner
        Goal_YSpinnerLabel         matlab.ui.control.Label
        Goal_XSpinner              matlab.ui.control.Spinner
        Goal_XSpinnerLabel         matlab.ui.control.Label
        Initial_YSpinner           matlab.ui.control.Spinner
        Initial_YSpinnerLabel      matlab.ui.control.Label
        Initial_XSpinner           matlab.ui.control.Spinner
        Initial_XSpinnerLabel      matlab.ui.control.Label
        StartButton                matlab.ui.control.Button
        UIAxes3                    matlab.ui.control.UIAxes
        UIAxes4                    matlab.ui.control.UIAxes
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.ElapsedTimeTextArea.Value = sprintf("")
            app.CommandsTextArea.Value = sprintf('Navigation of a Unicycle-Like Vehicle has Started!!');
            x_initial = app.Initial_XSpinner.Value;
            y_initial = app.Initial_YSpinner.Value;

            x_goal = app.Goal_XSpinner.Value;
            y_goal = app.Goal_YSpinner.Value;

            global stop_button pause_button continue_button;

            stop_button = 0; pause_button = 0; continue_button = 0;

            x=[x_initial y_initial pi/2 0 0]';% Robot initial state [x(m),y(m),yaw(Rad),v(m/s),ω(rad/s)]
            goal=[x_goal,y_goal];% Goal position [x(m),y(m)]
            % Obstacle list [x(m) y(m)]
            obstacle = load('obstacle.txt'); % You can load the obstacle from text file
            %obstacle = load('obstacle2.txt');
            %obstacle = load('obstacle3.txt');
            %obstacle = load('obstacle4.txt');
%             obstacle=[0 2;
%                       4 2;
%                       4 4;
%                       5 4;
%                       5 5;
%                       5 6;
%                       5 9;
%                       8 8;
%                       8 9;
%                       7 9]; % Also, you can define obstacles like here
                  
            obstacleR=0.5;% Radius of obstacle for collision detection
            global dt; dt=0.1; % Stepping time [s]
            
            % %%%%Robotic Kinematic Variable%%%%%%%
            % Maximum Linear Velocity [m / s]
            % Maximum Angular Velocity [rad / s], 
            % Maximum acceleration / deceleration [m / s^2], 
            % Maximum Angular acceleration / deceleration [rad / s^2],
            % Velocity resolution [m / s]
            % Angular velocity resolution [rad / s]]
            Kinematic=[1.0,toRadian(20.0),0.2,toRadian(50.0),0.01,toRadian(1)];
            
            % Evaluation function parameters [heading,dist,velocity,predictDT]
            evalParam=[0.1,0.2,0.1,3.0]; % alpha, beta, gamma, evaldt
            area=[-1 12 -1 12];% The size of the simulation area [xmin xmax ymin ymax]
            
            % simulation result
            result.x=[]; % Accumulate and store the state value of the trajectory points traversed
            tic; % Start of estimating program running time
            %movcount=0;
            % Main loop
            
            for i=1:5000 % Cycle operation 5000 times, guide to reach the destination or end of 5000 times operation


                % Calculation of input value by DWA
                [u,traj]=DynamicWindowApproach(x,Kinematic,goal,evalParam,obstacle,obstacleR);
                
                %Stop Simulation
                if stop_button == 1
                    break;
                end

                x=f(x,u);%Movement by motion model
                
                %Saving simulation results
                result.x=[result.x; x']; % The latest result is added to result.x as a column
                
                %Goal judgment
                if norm(x(1:2)-goal')<0.5
                    if x_initial==x_goal && y_initial==y_goal
                        app.CommandsTextArea.Value = sprintf("Please Enter Different Initial and Goal Locations!!");
                        break;
                    end
                    elapsed_time = toc;
                    title("2D Navigation Space")
                    lgd = legend({"Robot","Trajectory","Goal Location","Obstactle"},'FontSize',7);
                    lgd.NumColumns = 2;
                    legend('boxoff');
                    app.CommandsTextArea.Value = sprintf('The Robot Reached the Goal!!!!');
                    app.ElapsedTimeTextArea.Value = sprintf('The robot reached the goal location in %f seconds',elapsed_time);
                    break;
                end
                
                if x_initial>12 | x_goal>12 | y_initial>12| y_goal>12 
                    app.CommandsTextArea.Value = sprintf("Initial and Goal Locations must be between 0-12");
                    break;
                end
                
                if x_initial<0 | x_goal<0 | y_initial<0| y_goal<0
                    app.CommandsTextArea.Value = sprintf("Initial and Goal Locations must be between 0-12");
                    break;
                end

                %====Animation====
                hold off;
                ArrowLength=0.5;%Arrow length
                %robot
                
                quiver(x(1),x(2),ArrowLength*cos(x(3)),ArrowLength*sin(x(3)),'sk');hold on;
                plot(result.x(:,1),result.x(:,2),'-b');hold on;
                plot(goal(1),goal(2),'or');hold on;
                plot(obstacle(:,1),obstacle(:,2),'*k');hold on;
                
                % Search trajectory display
                if ~isempty(traj)
                    for it=1:length(traj(:,1))/5
                        ind=1+(it-1)*5;
                        plot(traj(ind,:),traj(ind+1,:),'-g');hold on;
                    end
                end
                axis(area);
                grid on;
                drawnow;
                %movcount=movcount+1;
                %mov(movcount) = getframe(gcf);% Get an animation frame
            end


            plot(app.UIAxes,result.x(:,1),result.x(:,2));
            
            plot(app.UIAxes2,result.x(:,4));
            
            plot(app.UIAxes3,result.x(:,5));
            
            plot(app.UIAxes4,result.x(:,3));
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            global stop_button
            stop_button = 1;
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            uiwait
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            uiresume
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Trajectory of the Robot')
            xlabel(app.UIAxes, 'Robot Position in x axis [m]')
            ylabel(app.UIAxes, 'Robot Position in y axis [m]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Velocity of the Robot')
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'Velocity [m/s]')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Orientation Angle of the Robot')
            xlabel(app.UIAxes4, 'Time')
            ylabel(app.UIAxes4, 'Orientation Angle [Rad]')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Angular Velocity of the Robot')
            xlabel(app.UIAxes3, 'Time')
            ylabel(app.UIAxes3, 'Angular Velocity [rad/s]')
            zlabel(app.UIAxes3, 'Z')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start Button';
            app.Initial_XSpinnerLabel = uilabel(app.UIFigure);
            app.Initial_XSpinnerLabel.Text = 'Initial_X';
            app.Initial_XSpinner = uispinner(app.UIFigure);
            app.Initial_YSpinnerLabel = uilabel(app.UIFigure);
            app.Initial_YSpinnerLabel.Text = 'Initial_Y';
            app.Initial_YSpinner = uispinner(app.UIFigure);
            app.Goal_XSpinnerLabel = uilabel(app.UIFigure);
            app.Goal_XSpinnerLabel.Text = 'Goal_X';
            app.Goal_XSpinner = uispinner(app.UIFigure);
            app.Goal_YSpinnerLabel = uilabel(app.UIFigure);
            app.Goal_YSpinnerLabel.Text = 'Goal_Y';
            app.Goal_YSpinner = uispinner(app.UIFigure);
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop Button';
            app.CommandsTextAreaLabel = uilabel(app.UIFigure);
            app.CommandsTextAreaLabel.Text = 'Commands';
            app.CommandsTextArea = uitextarea(app.UIFigure);
            app.CommandsTextArea.Value = {'Please Enter The Initial and Goal Location'};
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause Button';
            app.ContinueButton = uibutton(app.UIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.ElapsedTimeTextAreaLabel = uilabel(app.UIFigure);
            app.ElapsedTimeTextAreaLabel.Text = 'Elapsed Time';
            app.ElapsedTimeTextArea = uitextarea(app.UIFigure);
            app.RobotInitialLocationLabel = uilabel(app.UIFigure);
            app.RobotInitialLocationLabel.Text = 'Robot Initial Location';
            app.RobotGoalLocationLabel = uilabel(app.UIFigure);
            app.RobotGoalLocationLabel.Text = 'Robot Goal Location';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = run

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end